classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MenuPanel                       matlab.ui.container.Panel
        ParcourirButton                 matlab.ui.control.Button
        CompressionButton               matlab.ui.control.Button
        DecompressionButton             matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        FormadesortiePanel              matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        IRMButton                       matlab.ui.control.RadioButton
        TypedecompressionPanel          matlab.ui.container.Panel
        RLELabel                        matlab.ui.control.Label
        InformationPanel                matlab.ui.container.Panel
        TypedimageLabel                 matlab.ui.control.Label
        TypedimageEditField             matlab.ui.control.EditField
        LargeurEditFieldLabel           matlab.ui.control.Label
        LargeurEditField                matlab.ui.control.NumericEditField
        HauteurLabel                    matlab.ui.control.Label
        HauteurEditField                matlab.ui.control.NumericEditField
        TailleavantcompressionEditFieldLabel  matlab.ui.control.Label
        TailleavantcompressionEditField  matlab.ui.control.NumericEditField
        TailleaprscompressionEditFieldLabel  matlab.ui.control.Label
        TailleaprscompressionEditField  matlab.ui.control.NumericEditField
        TauxdecompressionEditFieldLabel  matlab.ui.control.Label
        TauxdecompressionEditField      matlab.ui.control.NumericEditField
        VisionneurdesimagesPanel        matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: ParcourirButton
        function ParcourirButtonPushed(app, event)
            global f;
            global p;
            global im;
            global map;
            global type;
            global width;
            global height;
            global taille;
            [f,p]=uigetfile({'*.*'});
            [ im , map ]=imread([p,f]);
            [type,width,height,taille]=infoimage([p f]);
            app.TypedimageEditField.Value=type;
            app.LargeurEditField.Value=width;
            app.HauteurEditField.Value=height;
            app.TailleavantcompressionEditField.Value=taille;
            imshow(im,map,'Parent',app.UIAxes);
        end

        % Value changed function: TypedimageEditField
        function TypedimageEditFieldValueChanged(app, event)
          
            value = app.TypedimageEditField.Value;
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.UIFigure.Visible='off';
            App1();
            close all;
            close(app.UIFigure);
        end

        % Button pushed function: CompressionButton
        function CompressionButtonPushed(app, event)
            global file;
            global taille;
            global im;
            global map;
            file=uiputfile;
            writeIRM(im,map,file);
            [taille_compress,taux]=infoo(file,taille);
            app.TailleaprscompressionEditField.Value=taille_compress;
            app.TauxdecompressionEditField.Value=taux;
        end

        % Value changed function: TailleaprscompressionEditField
        function TailleaprscompressionEditFieldValueChanged(app, event)
            value = app.TailleaprscompressionEditField.Value;
            
        end

        % Value changed function: TauxdecompressionEditField
        function TauxdecompressionEditFieldValueChanged(app, event)
            value = app.TauxdecompressionEditField.Value;
            
        end

        % Button pushed function: DecompressionButton
        function DecompressionButtonPushed(app, event)
            global file;
            [y,map] = readIRM(file)
            imshow(y,map,'Parent',app.UIAxes2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.MenuPanel = uipanel(app.UIFigure);
            app.MenuPanel.Title = 'Menu';
            app.ParcourirButton = uibutton(app.MenuPanel, 'push');
            app.ParcourirButton.ButtonPushedFcn = createCallbackFcn(app, @ParcourirButtonPushed, true);
            app.ParcourirButton.Text = 'Parcourir';
            app.CompressionButton = uibutton(app.MenuPanel, 'push');
            app.CompressionButton.ButtonPushedFcn = createCallbackFcn(app, @CompressionButtonPushed, true);
            app.CompressionButton.Text = 'Compression';
            app.DecompressionButton = uibutton(app.MenuPanel, 'push');
            app.DecompressionButton.ButtonPushedFcn = createCallbackFcn(app, @DecompressionButtonPushed, true);
            app.DecompressionButton.Text = 'Decompression';
            app.ClearButton = uibutton(app.MenuPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FormadesortiePanel = uipanel(app.UIFigure);
            app.FormadesortiePanel.Title = 'Forma de sortie';
            app.ButtonGroup = uibuttongroup(app.FormadesortiePanel);
            app.IRMButton = uiradiobutton(app.ButtonGroup);
            app.IRMButton.Text = 'IRM';
            app.IRMButton.Value = true;
            app.TypedecompressionPanel = uipanel(app.UIFigure);
            app.TypedecompressionPanel.Title = 'Type de compression';
            app.RLELabel = uilabel(app.TypedecompressionPanel);
            app.RLELabel.Text = 'RLE';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information ';
            app.TypedimageLabel = uilabel(app.InformationPanel);
            app.TypedimageLabel.Text = 'Type d''image                        :';
            app.TypedimageEditField = uieditfield(app.InformationPanel, 'text');
            app.TypedimageEditField.ValueChangedFcn = createCallbackFcn(app, @TypedimageEditFieldValueChanged, true);
            app.LargeurEditFieldLabel = uilabel(app.InformationPanel);
            app.LargeurEditFieldLabel.Text = 'Largeur                                 :';
            app.LargeurEditField = uieditfield(app.InformationPanel, 'numeric');
            app.HauteurLabel = uilabel(app.InformationPanel);
            app.HauteurLabel.Text = 'Hauteur                                 :';
            app.HauteurEditField = uieditfield(app.InformationPanel, 'numeric');
            app.TailleavantcompressionEditFieldLabel = uilabel(app.InformationPanel);
            app.TailleavantcompressionEditFieldLabel.Text = 'Taille avant compression :';
            app.TailleavantcompressionEditField = uieditfield(app.InformationPanel, 'numeric');
            app.TailleaprscompressionEditFieldLabel = uilabel(app.InformationPanel);
            app.TailleaprscompressionEditFieldLabel.Text = 'Taille après compression :';
            app.TailleaprscompressionEditField = uieditfield(app.InformationPanel, 'numeric');
            app.TailleaprscompressionEditField.ValueChangedFcn = createCallbackFcn(app, @TailleaprscompressionEditFieldValueChanged, true);
            app.TauxdecompressionEditFieldLabel = uilabel(app.InformationPanel);
            app.TauxdecompressionEditFieldLabel.Text = 'Taux de compression        :';
            app.TauxdecompressionEditField = uieditfield(app.InformationPanel, 'numeric');
            app.TauxdecompressionEditField.ValueChangedFcn = createCallbackFcn(app, @TauxdecompressionEditFieldValueChanged, true);
            app.VisionneurdesimagesPanel = uipanel(app.UIFigure);
            app.VisionneurdesimagesPanel.Title = 'Visionneur  des images :';
            app.UIAxes = uiaxes(app.VisionneurdesimagesPanel);
            title(app.UIAxes, 'Image d''entrée');
            app.UIAxes2 = uiaxes(app.VisionneurdesimagesPanel);
            title(app.UIAxes2, 'Image de sortie');
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end